classdef ADSB_Main_Plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        MarkerLabelTab                  matlab.ui.container.Tab
        MinNICValueSpinner              matlab.ui.control.Spinner
        MinNICValueSpinnerLabel         matlab.ui.control.Label
        MaxNICValueSpinner              matlab.ui.control.Spinner
        MaxNICValueSpinnerLabel         matlab.ui.control.Label
        MarkersByButtonGroup            matlab.ui.container.ButtonGroup
        NIC7Button                      matlab.ui.control.RadioButton
        AircraftNumberButton            matlab.ui.control.RadioButton
        NICValueButton                  matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        SaveFilteredDataTab             matlab.ui.container.Tab
        ExportasmatfileButton           matlab.ui.control.Button
        ExportasCSVButton               matlab.ui.control.Button
        SaveFiguresTab                  matlab.ui.container.Tab
        AltGSCheckBox                   matlab.ui.control.CheckBox
        NICCountCheckBox                matlab.ui.control.CheckBox
        LonAltCheckBox                  matlab.ui.control.CheckBox
        LatAltCheckBox                  matlab.ui.control.CheckBox
        AerialPlotCheckBox              matlab.ui.control.CheckBox
        TopoPlotCheckBox                matlab.ui.control.CheckBox
        ExportFiguresButton             matlab.ui.control.Button
        TimeWindowPanel                 matlab.ui.container.Panel
        SelectEndDate                   matlab.ui.control.DatePicker
        SelectStartDateDatePicker_2Label  matlab.ui.control.Label
        SelectStartDate                 matlab.ui.control.DatePicker
        SelectStartDateDatePickerLabel  matlab.ui.control.Label
        SelectStartHourUTCDropDown      matlab.ui.control.DropDown
        SelectStartHourUTCDropDownLabel  matlab.ui.control.Label
        SelectEndHourUTCDropDown        matlab.ui.control.DropDown
        SelectEndHourUTCDropDownLabel   matlab.ui.control.Label
        BottomTabs                      matlab.ui.container.TabGroup
        LatitudeTab                     matlab.ui.container.Tab
        LatAxes                         matlab.ui.control.UIAxes
        LongitudeTab                    matlab.ui.container.Tab
        LonAxes                         matlab.ui.control.UIAxes
        AltitudeandGroundSpeedTab       matlab.ui.container.Tab
        VelAltAxes                      matlab.ui.control.UIAxes
        AircraftInformationPanel        matlab.ui.container.Panel
        DurationofFlightEditField       matlab.ui.control.EditField
        DurationofFlightEditFieldLabel  matlab.ui.control.Label
        TOCEditField                    matlab.ui.control.NumericEditField
        TOCEditFieldLabel               matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        TailNumberEditField             matlab.ui.control.EditField
        TailNumberEditFieldLabel        matlab.ui.control.Label
        AircraftModelEditField          matlab.ui.control.EditField
        AircraftModelEditFieldLabel     matlab.ui.control.Label
        SelectAircraftDropDown          matlab.ui.control.DropDown
        SelectAircraftDropDownLabel     matlab.ui.control.Label
        FilterByAircraftPanel           matlab.ui.container.Panel
        SelectAircraftListBox           matlab.ui.control.ListBox
        SelectAllAircraftButton         matlab.ui.control.Button
        TopTabs                         matlab.ui.container.TabGroup
        TopoMapTab                      matlab.ui.container.Tab
        AerialMapTab                    matlab.ui.container.Tab
        AerialAxes                      matlab.ui.control.UIAxes
        NICCountPlotTab                 matlab.ui.container.Tab
        NICCountAxes                    matlab.ui.control.UIAxes
        FlightPathAnimationTab          matlab.ui.container.Tab
        TimeWindowField                 matlab.ui.control.NumericEditField
        TimeWindowminEditFieldLabel     matlab.ui.control.Label
        CheckVideoDirectoryforResultingVideoLabel  matlab.ui.control.Label
        VideoFileType                   matlab.ui.control.DropDown
        SpecifyFileTypeDropDownLabel    matlab.ui.control.Label
        FrameRate                       matlab.ui.control.NumericEditField
        SpecifyFrameRatefpsEditFieldLabel  matlab.ui.control.Label
        FlightPathAnimationLabel        matlab.ui.control.Label
        VideoFilename                   matlab.ui.control.EditField
        SpecifyVideoFileNameEditFieldLabel  matlab.ui.control.Label
        CreateVideoButton               matlab.ui.control.Button
        UploadFilesButton               matlab.ui.control.Button
    end


    properties (Access = public)
        %Data sources
        flightdata=1; 
        filteredata=1; 
        hours=[];
        boxLon=[];
        boxLat=[];
        %Selected Aircraft
        aircraft='';
        num_aircraft=1;
        %NIC value criteria
        %marker information
        markertype='';
        %directory/path information
        current_dir=cd;
        registry_dir=fileparts(string(which('ADSBtools.util.loadAircraftDatabase')))
        reg_data=[];
        TopoAxes=[];
    end

    methods (Access = private)
        %Plot Position Flight Data
        function PlotData(app)
            %Create boundary limits
            app.boxLon=[min(app.filteredata.lon),max(app.filteredata.lon)];
            app.boxLat=[min(app.filteredata.lat),max(app.filteredata.lat)];
            %Clear all figures
            if class(app.TopoAxes)=="matlab.graphics.axis.GeographicAxes"
                cla(app.TopoAxes,'reset')
            else
                app.TopoAxes=geoaxes(app.TopoMapTab);
            end
            cla(app.LatAxes,'reset');cla(app.LonAxes,'reset');cla(app.VelAltAxes,'reset');...
                cla(app.AerialAxes,'reset');cla(app.NICCountAxes);
            %% Plot Figures
            %Plot the Topographic Map
            ADSBtools.plot.plot_geo(app.filteredata.time,app.filteredata.lat,...
                app.filteredata.lon,app.filteredata.nic,app.filteredata.icao,...
                'axis',app.TopoAxes,'markertype',app.markertype,'boxLon',app.boxLon,...
                'boxLat',app.boxLat);
            %Plot the 3D map            
%             ADSBtools.plot.plot_3Dterrain(app.filteredata.time, app.filteredata.lon,...
%                 app.filteredata.lat,app.filteredata.alt, app.filteredata.nic,...
%                 app.filteredata.icao, 'axis',app.AerialAxes,'markertype',app.markertype)

            %Plot the Latitude Axes
            ADSBtools.plot.plot_2D_LatAlt(app.filteredata.time,app.filteredata.lat,...
                app.filteredata.alt,app.filteredata.nic,app.filteredata.icao, ...
                'axis',app.LatAxes,'markertype',app.markertype)
            %Plot the Longitude Axes
            ADSBtools.plot.plot_2D_LonAlt(app.filteredata.time,app.filteredata.lon,...
                app.filteredata.alt,app.filteredata.nic,app.filteredata.icao, ...
                'axis',app.LonAxes,'markertype',app.markertype)
            %Plot the NICCount Plot Axes
            ADSBtools.plot.plot_niccount(app.filteredata.time,app.filteredata.nic, ...
                'axis',app.NICCountAxes)
            %Plot the groundspeed and altitude plot (for selected aircraft
            %only)
            if ~isempty(app.SelectAircraftListBox.Value)
                veldata=cell(1,numel(app.SelectAircraftListBox.Value));
                for i=1:numel(app.SelectAircraftListBox.Value)
                    veldata{i}=ADSBtools.intrpl.CalcDynamics(app.filteredata);
                end
                veldatatotal=vertcat(veldata{:});
                ADSBtools.plot.plot_speed(veldatatotal.time,veldatatotal.velocity, ...
                    veldatatotal.alt,veldatatotal.nic,veldatatotal.icao, ...
                    'axis',app.VelAltAxes)
            end
        end

        %Refresh Aircraft Data List in ListBox
        function RefreshAC(app)
            %Reset List
            app.SelectAircraftListBox.Items={};
            %Add Aircraft to ListBox
            app.aircraft=unique(app.filteredata.icao);
            app.num_aircraft=length(app.aircraft);
            for i=1:app.num_aircraft
                app.SelectAircraftListBox.Items(i)=app.aircraft(i);
            end
        end

        %Resets Data Display in GUI
        function ResetData(app)
            app.filteredata=app.flightdata;
            app.MinNICValueSpinner.Value=min(app.flightdata.nic);
            app.MaxNICValueSpinner.Value=max(app.flightdata.nic);
            app.SelectStartHourUTCDropDown.Value=string(app.hours(1));
            app.SelectEndHourUTCDropDown.Value=string(app.hours(end));
        end
        
        %Determine Marker Type
        function MarkerType(app)  
            if app.NICValueButton.Value==1
                app.markertype='NIC';
            elseif app.AircraftNumberButton.Value==1
                app.markertype='ICAO';
            elseif app.NIC7Button.Value==1
                app.markertype='Interfere';
            end
        end
        
        %Upload OpenSky Registry File
        function RegistryUpload(app)
            app.reg_data=readtable(strcat(app.registry_dir,'\aircraftDatabase_OpenSky.csv'));
        end
        
        
        function SelectFile(app)
            [file_csv,path_csv] = uigetfile(strcat(app.current_dir,'\*.csv'),...
                'MultiSelect','on');
            %If user chose one file
            if numel(string(file_csv))==1
                app.flightdata=readtable([path_csv,file_csv],'Format','auto');
                app.flightdata.nic=round(app.flightdata.nic);
                if ismember("mintime",app.flightdata.Properties.VariableNames) 
                        app.flightdata = renamevars(app.flightdata,"mintime","time");
                end
                if ismember("icao24",app.flightdata.Properties.VariableNames) 
                    app.flightdata=renamevars(app.flightdata,"icao24","icao");
                end
            %If user chose multiple files
            else
                hourdata=cell(1,numel(file_csv));
                for i=1:numel(file_csv)
                    table=readtable([path_csv,file_csv{i}],'Format','auto');
                    if ismember("mintime",table.Properties.VariableNames) 
                        table=renamevars(table,"mintime","time");
                    end
                    if ismember("icao24",table.Properties.VariableNames) 
                        table=renamevars(table,"icao24","icao");
                    end
                    t_ind=find(string(table.Properties.VariableNames)=="time");
                    lat_ind=find(string(table.Properties.VariableNames)=="lat");
                    lon_ind=find(string(table.Properties.VariableNames)=="lon");
                    alt_ind=find(string(table.Properties.VariableNames)=="alt");
                    nic_ind=find(string(table.Properties.VariableNames)=="nic");
                    icao_ind=find(string(table.Properties.VariableNames)=="icao");

                    hourdata{i}=table(:,[t_ind,icao_ind,lat_ind,lon_ind,alt_ind, ...
                        nic_ind]);
                    
                    fprintf("File %s\n",num2str(i))

                end
                app.flightdata=vertcat(hourdata{:});
                app.flightdata.nic=round(app.flightdata.nic);
            end
            %Sort Data by chronological order
            app.flightdata=sortrows(app.flightdata,'time','ascend');
        end
        
        %Places Hours in Drop Down Menus, places Limits on date pickers
        function DateSelect(app)
            %Place hours in drop down menus
            app.hours=unique(hms(app.flightdata.time));
            [app.SelectStartHourUTCDropDown.Items,app.SelectEndHourUTCDropDown.Items]=deal(string(app.hours));
            %Place Limits on Calendar
            [ymin,mmin,dmin]=ymd(min(app.flightdata.time));
            [ymax,mmax,dmax]=ymd(max(app.flightdata.time));
            [app.SelectStartDate.Limits,app.SelectEndDate.Limits]=deal([datetime(ymin,mmin,dmin),datetime(ymax,mmax,dmax)]);
            app.SelectStartDate.Value=app.SelectStartDate.Limits(1);
            app.SelectEndDate.Value=app.SelectStartDate.Limits(2);
        end
        
        %Filter Data
        function FilterData(app)        
            %Filter by Date
            app.filteredata=app.flightdata(app.flightdata.time>dateshift(app.SelectStartDate.Value,'start','day') &...
                app.flightdata.time<dateshift(app.SelectEndDate.Value,'end','day'),:);
            %Filter by Hour
             app.filteredata=app.filteredata(hms(app.filteredata.time)>=str2double(app.SelectStartHourUTCDropDown.Value) &...
                hms(app.filteredata.time)<=str2double(app.SelectEndHourUTCDropDown.Value),:);
            %Filter by NIC
            app.filteredata=app.filteredata(app.filteredata.nic>=app.MinNICValueSpinner.Value...
                & app.filteredata.nic<=app.MaxNICValueSpinner.Value,:);
            %Update NIC Counters
            app.MinNICValueSpinner.Value=min(app.filteredata.nic);
            app.MaxNICValueSpinner.Value=max(app.filteredata.nic);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load registry directory
            RegistryUpload(app)
        end

        % Button pushed function: UploadFilesButton
        function UploadFilesButtonPushed(app, event)
            %Select File 
            SelectFile(app)
            %Set hours discretely in start/end hour time window
            DateSelect(app)
            %Reset Data in GUI
            ResetData(app)
            %Determine Marker Type
            MarkerType(app)
            %Plot Data
            PlotData(app)
            %Refresh Aircraft List
            RefreshAC(app)
        end

        % Value changed function: MinNICValueSpinner
        function MinNICValueSpinnerValueChanged(app, event)
            FilterData(app)
            %Refresh Aircraft
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: MaxNICValueSpinner
        function MaxNICValueSpinnerValueChanged(app, event)
            FilterData(app)
            %Refresh Aircraft
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Callback function: SelectAircraftDropDown,
        % SelectAircraftDropDown, 
        % ...and 1 other component
        function SelectAircraftListBoxValueChanged(app, event)
            filt_aircraft = app.SelectAircraftListBox.Value;
            app.SelectAircraftDropDown.Enable="on";
            app.SelectAircraftDropDown.Items=filt_aircraft;
            app.filteredata=app.flightdata(ismember(app.flightdata.icao,filt_aircraft),:);
            app.MinNICValueSpinner.Value=min(app.filteredata.nic);
            app.MaxNICValueSpinner.Value=max(app.filteredata.nic);
            %Refresh Plots
            PlotData(app)
            %Report Aircraft Information
            spec_aircraft = app.SelectAircraftDropDown.Value; 
            %Find Aircraft ICAO Code in Registry
            ac_ind=find(strcmp(app.reg_data.icao24,lower(spec_aircraft)));          
            
            %Input Entries
            if isempty(ac_ind)
                app.TailNumberEditField.Value='N/A';
                app.AircraftModelEditField.Value='N/A';
                
            else
                app.TailNumberEditField.Value=app.reg_data.registration{ac_ind};
                app.AircraftModelEditField.Value=app.reg_data.model{ac_ind};
            end
            specdata=app.filteredata(strcmp(app.filteredata.icao,spec_aircraft),:);
            app.TOCEditField.Value=max(specdata.alt);
            app.StartTimeEditField.Value=datestr(specdata.time(1));
            app.EndTimeEditField.Value=datestr(specdata.time(end));
            app.DurationofFlightEditField.Value=string(specdata.time(end)...
                -specdata.time(1));
        end

        % Button pushed function: SelectAllAircraftButton
        function SelectAllAircraftButtonPushed(app, event)
            app.SelectAircraftListBox.Value={};
            app.SelectAircraftDropDown.Items={};
            app.SelectAircraftDropDown.Enable="off";
            %Reset Data in GUI
            ResetData(app)
            %Refresh Plots
            PlotData(app)
            %Reset Aircraft LIst
            RefreshAC(app)

        end

        % Selection changed function: MarkersByButtonGroup
        function MarkerSelectionChange(app, event)
            %Determine Marker Type
            MarkerType(app)
            %Refresh Plots
            PlotData(app)
        end

        % Button pushed function: ExportasCSVButton
        function ExportasCSVButtonPushed(app, event)
            [filename,pathname]=uiputfile('FilteredData.csv');
            fullname=strcat(pathname,filename);
            writetable(app.filteredata,fullname)
        end

        % Button pushed function: CreateVideoButton
        function CreateVideoButtonPushed(app, event)
            % Run animation (data,framerate,option to include centroid, and
            % centroid plot type
            %filetype,filename,framerate,centoption,boxLat,boxLon)
            ADSBtools.plot.plot_flightanim(app.filteredata,...
                'filetype',app.VideoFileType.Value,'filename',app.VideoFilename.Value,...
                'framerate',app.FrameRate.Value,'boxlat',app.boxLat, ...
                'boxlon',app.boxLon,'timewindow',app.TimeWindowField.Value)
        end

        % Value changed function: SelectStartHourUTCDropDown
        function SelectStartHourUTCDropDownValueChanged(app, event)
            FilterData(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: SelectEndHourUTCDropDown
        function SelectEndHourUTCDropDownValueChanged(app, event)
            FilterData(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: SelectStartDate
        function SelectStartDateValueChanged(app, event)
            FilterData(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)            
        end

        % Value changed function: SelectEndDate
        function SelectEndtDateValueChanged(app, event)
            FilterData(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app) 
        end

        % Callback function
        function IncludeLocalizationDynamicValueChanged(app, event)
            value = app.IncludeLocalizationDynamic.Value;
            if strcmp(value,'No')==1
                app.SelectLocalizationMethodDropDownDynamic.Enable="off";
            else
                app.SelectLocalizationMethodDropDownDynamic.Enable="on";
            end
        end

        % Button down function: AerialAxes
        function AerialAxesButtonDown(app, event)
        set(app.AerialAxes,'ActionPostCallback',@ADSBtools.plot.plot_google_map.update_google_map_fig)
        set(app.AerialAxes,'ActionPostCallback',@update_google_map_fig)
        set(app.AerialAxes, 'ActionPostCallback', @update_google_map_fig);   
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            %Ask User which figures they would like to save
            choice=[app.TopoPlotCheckBox.Value app.AerialPlotCheckBox.Value,...
                app.LatAltCheckBox.Value app.LonAltCheckBox.Value app.NICCountCheckBox.Value,...
                app.AltGSCheckBox.Value];
            plots={app.TopoAxes, app.AerialAxes,app.LatAxes,app.LonAxes,app.NICCountAxes,...
                app.VelAltAxes};
            plotnames=["Topographic","Aerial","Lat-Alt","Lon-Alt","NICCount","Alt-GS"];
            for i=1:numel(choice)
                if choice(i)==1
                    newfig=figure;
                    copyobj(plots{i},newfig)
                    set(gca,'Units','Normalized')
                    set(gca,'Position',[0.05 0.05 0.9 0.9])
                    filename=cd+"\plots\"+plotnames(i)+".fig";
                    savefig(newfig,filename, 'compact')
                    delete(newfig)
                end
            end
        end

        % Button pushed function: ExportasmatfileButton
        function ExportasmatfileButtonPushed(app, event)
            %If this button is pressed, this needs to open the EventUpload
            %App, where the csv file is imported
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UploadFilesButton = uibutton(app.GridLayout, 'push');
            app.UploadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilesButtonPushed, true);
            app.UploadFilesButton.Text = 'Upload Files';
            app.TopTabs = uitabgroup(app.GridLayout);
            app.TopoMapTab = uitab(app.TopTabs);
            app.TopoMapTab.Title = 'TopoPlot';
            app.AerialMapTab = uitab(app.TopTabs);
            app.AerialMapTab.Title = '3D Map';
            app.AerialAxes = uiaxes(app.AerialMapTab);
            xlabel(app.AerialAxes, 'Longitude [degrees]')
            ylabel(app.AerialAxes, 'Latitude [degrees]')
            zlabel(app.AerialAxes, 'Altitude [meters]')
            app.AerialAxes.ButtonDownFcn = createCallbackFcn(app, @AerialAxesButtonDown, true);
            app.NICCountPlotTab = uitab(app.TopTabs);
            app.NICCountPlotTab.Title = 'NIC Count Plot';
            app.NICCountAxes = uiaxes(app.NICCountPlotTab);
            title(app.NICCountAxes, 'Title')
            xlabel(app.NICCountAxes, 'Time (UTC)')
            ylabel(app.NICCountAxes, 'Percentage of NIC values over 15 min')
            zlabel(app.NICCountAxes, 'Z')
            app.FlightPathAnimationTab = uitab(app.TopTabs);
            app.FlightPathAnimationTab.Title = 'Flight Path Animation';
            app.CreateVideoButton = uibutton(app.FlightPathAnimationTab, 'push');
            app.CreateVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CreateVideoButtonPushed, true);
            app.CreateVideoButton.Text = 'Create Video';
            app.SpecifyVideoFileNameEditFieldLabel = uilabel(app.FlightPathAnimationTab);
            app.SpecifyVideoFileNameEditFieldLabel.Text = 'Specify Video File Name';
            app.VideoFilename = uieditfield(app.FlightPathAnimationTab, 'text');
            app.FlightPathAnimationLabel = uilabel(app.FlightPathAnimationTab);
            app.FlightPathAnimationLabel.Text = 'Flight Path Animation';
            app.SpecifyFrameRatefpsEditFieldLabel = uilabel(app.FlightPathAnimationTab);
            app.SpecifyFrameRatefpsEditFieldLabel.Text = 'Specify Frame Rate (fps)';
            app.FrameRate = uieditfield(app.FlightPathAnimationTab, 'numeric');
            app.SpecifyFileTypeDropDownLabel = uilabel(app.FlightPathAnimationTab);
            app.SpecifyFileTypeDropDownLabel.Text = 'Specify File Type';
            app.VideoFileType = uidropdown(app.FlightPathAnimationTab);
            app.VideoFileType.Items = {'Uncompressed AVI', 'Motion JPEG AVI', 'MPEG-4'};
            app.VideoFileType.Value = 'Uncompressed AVI';
            app.CheckVideoDirectoryforResultingVideoLabel = uilabel(app.FlightPathAnimationTab);
            app.CheckVideoDirectoryforResultingVideoLabel.Text = '(Check ''Video Directory'' for Resulting Video)';
            app.TimeWindowminEditFieldLabel = uilabel(app.FlightPathAnimationTab);
            app.TimeWindowminEditFieldLabel.Text = 'Time Window (min)';
            app.TimeWindowField = uieditfield(app.FlightPathAnimationTab, 'numeric');
            app.FilterByAircraftPanel = uipanel(app.GridLayout);
            app.FilterByAircraftPanel.Title = 'Filter By Aircraft';
            app.SelectAllAircraftButton = uibutton(app.FilterByAircraftPanel, 'push');
            app.SelectAllAircraftButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllAircraftButtonPushed, true);
            app.SelectAllAircraftButton.Text = 'Select All Aircraft';
            app.SelectAircraftListBox = uilistbox(app.FilterByAircraftPanel);
            app.SelectAircraftListBox.Items = {};
            app.SelectAircraftListBox.Multiselect = 'on';
            app.SelectAircraftListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftListBox.Value = {};
            app.AircraftInformationPanel = uipanel(app.GridLayout);
            app.AircraftInformationPanel.Title = 'Aircraft Information';
            app.SelectAircraftDropDownLabel = uilabel(app.AircraftInformationPanel);
            app.SelectAircraftDropDownLabel.Enable = 'off';
            app.SelectAircraftDropDownLabel.Text = 'Select Aircraft';
            app.SelectAircraftDropDown = uidropdown(app.AircraftInformationPanel);
            app.SelectAircraftDropDown.Items = {};
            app.SelectAircraftDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftDropDown.Enable = 'off';
            app.SelectAircraftDropDown.Value = {};
            app.AircraftModelEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.AircraftModelEditFieldLabel.Text = 'Aircraft Model';
            app.AircraftModelEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.TailNumberEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.TailNumberEditFieldLabel.Text = 'Tail Number';
            app.TailNumberEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.TailNumberEditField.Editable = 'off';
            app.StartTimeEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.StartTimeEditField.Editable = 'off';
            app.EndTimeEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.EndTimeEditField.Editable = 'off';
            app.TOCEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.TOCEditFieldLabel.Text = 'TOC';
            app.TOCEditField = uieditfield(app.AircraftInformationPanel, 'numeric');
            app.TOCEditField.Editable = 'off';
            app.DurationofFlightEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.DurationofFlightEditFieldLabel.Text = 'Duration of Flight';
            app.DurationofFlightEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.DurationofFlightEditField.Editable = 'off';
            app.BottomTabs = uitabgroup(app.GridLayout);
            app.LatitudeTab = uitab(app.BottomTabs);
            app.LatitudeTab.Title = 'Latitude';
            app.LatAxes = uiaxes(app.LatitudeTab);
            xlabel(app.LatAxes, 'Latitude [degrees]')
            ylabel(app.LatAxes, 'Baro Altitude [m]')
            zlabel(app.LatAxes, 'Z')
            app.LongitudeTab = uitab(app.BottomTabs);
            app.LongitudeTab.Title = 'Longitude';
            app.LonAxes = uiaxes(app.LongitudeTab);
            xlabel(app.LonAxes, 'Longitude [degrees]')
            ylabel(app.LonAxes, 'Baro Altitude [m]')
            zlabel(app.LonAxes, 'Z')
            app.AltitudeandGroundSpeedTab = uitab(app.BottomTabs);
            app.AltitudeandGroundSpeedTab.Title = 'Altitude and Ground Speed';
            app.VelAltAxes = uiaxes(app.AltitudeandGroundSpeedTab);
            xlabel(app.VelAltAxes, 'Time (UTC)')
            ylabel(app.VelAltAxes, 'Baro Altitude [m]')
            zlabel(app.VelAltAxes, 'Z')
            app.TimeWindowPanel = uipanel(app.GridLayout);
            app.TimeWindowPanel.Title = 'Time Window';
            app.SelectEndHourUTCDropDownLabel = uilabel(app.TimeWindowPanel);
            app.SelectEndHourUTCDropDownLabel.Text = 'Select End Hour (UTC)';
            app.SelectEndHourUTCDropDown = uidropdown(app.TimeWindowPanel);
            app.SelectEndHourUTCDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEndHourUTCDropDownValueChanged, true);
            app.SelectStartHourUTCDropDownLabel = uilabel(app.TimeWindowPanel);
            app.SelectStartHourUTCDropDownLabel.Text = 'Select Start Hour (UTC)';
            app.SelectStartHourUTCDropDown = uidropdown(app.TimeWindowPanel);
            app.SelectStartHourUTCDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectStartHourUTCDropDownValueChanged, true);
            app.SelectStartDateDatePickerLabel = uilabel(app.TimeWindowPanel);
            app.SelectStartDateDatePickerLabel.Text = 'Select Start Date';
            app.SelectStartDate = uidatepicker(app.TimeWindowPanel);
            app.SelectStartDate.ValueChangedFcn = createCallbackFcn(app, @SelectStartDateValueChanged, true);
            app.SelectStartDateDatePicker_2Label = uilabel(app.TimeWindowPanel);
            app.SelectStartDateDatePicker_2Label.Text = 'Select Start Date';
            app.SelectEndDate = uidatepicker(app.TimeWindowPanel);
            app.SelectEndDate.ValueChangedFcn = createCallbackFcn(app, @SelectEndtDateValueChanged, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SaveFilteredDataTab = uitab(app.TabGroup);
            app.SaveFilteredDataTab.Title = 'Save Filtered Data';
            app.ExportasCSVButton = uibutton(app.SaveFilteredDataTab, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVButtonPushed, true);
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.ExportasmatfileButton = uibutton(app.SaveFilteredDataTab, 'push');
            app.ExportasmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasmatfileButtonPushed, true);
            app.ExportasmatfileButton.Text = 'Export as mat file';
            app.SaveFiguresTab = uitab(app.TabGroup);
            app.SaveFiguresTab.Title = 'Save Figures';
            app.ExportFiguresButton = uibutton(app.SaveFiguresTab, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.TopoPlotCheckBox = uicheckbox(app.SaveFiguresTab);
            app.TopoPlotCheckBox.Text = 'TopoPlot';
            app.AerialPlotCheckBox = uicheckbox(app.SaveFiguresTab);
            app.AerialPlotCheckBox.Text = 'AerialPlot';
            app.LatAltCheckBox = uicheckbox(app.SaveFiguresTab);
            app.LatAltCheckBox.Text = 'Lat-Alt';
            app.LonAltCheckBox = uicheckbox(app.SaveFiguresTab);
            app.LonAltCheckBox.Text = 'Lon-Alt';
            app.NICCountCheckBox = uicheckbox(app.SaveFiguresTab);
            app.NICCountCheckBox.Text = 'NICCount';
            app.AltGSCheckBox = uicheckbox(app.SaveFiguresTab);
            app.AltGSCheckBox.Text = 'Alt-GS';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.MarkerLabelTab = uitab(app.TabGroup2);
            app.MarkerLabelTab.Title = 'Marker Label';
            app.MarkersByButtonGroup = uibuttongroup(app.MarkerLabelTab);
            app.MarkersByButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MarkerSelectionChange, true);
            app.MarkersByButtonGroup.Title = 'Markers By:';
            app.NICValueButton = uiradiobutton(app.MarkersByButtonGroup);
            app.NICValueButton.Text = 'NIC Value';
            app.NICValueButton.Value = true;
            app.AircraftNumberButton = uiradiobutton(app.MarkersByButtonGroup);
            app.AircraftNumberButton.Text = 'Aircraft Number';
            app.NIC7Button = uiradiobutton(app.MarkersByButtonGroup);
            app.NIC7Button.Text = 'NIC<7';
            app.MaxNICValueSpinnerLabel = uilabel(app.MarkerLabelTab);
            app.MaxNICValueSpinnerLabel.Text = 'Max. NIC Value';
            app.MaxNICValueSpinner = uispinner(app.MarkerLabelTab);
            app.MaxNICValueSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxNICValueSpinnerValueChanged, true);
            app.MinNICValueSpinnerLabel = uilabel(app.MarkerLabelTab);
            app.MinNICValueSpinnerLabel.Text = 'Min. NIC Value';
            app.MinNICValueSpinner = uispinner(app.MarkerLabelTab);
            app.MinNICValueSpinner.ValueChangedFcn = createCallbackFcn(app, @MinNICValueSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADSB_Main_Plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end