classdef MAIN_EventUpload < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloseWindowButton              matlab.ui.control.Button
        OutputDataPanel                matlab.ui.container.Panel
        PlotDataButton                 matlab.ui.control.Button
        SelectInterferenceMethod       matlab.ui.control.ListBox
        SelectInterferenceLocalizationMethodListBoxLabel  matlab.ui.control.Label
        PlotChoice                     matlab.ui.container.ButtonGroup
        PlotLocalizationMethodButton   matlab.ui.control.RadioButton
        ViewDataButton                 matlab.ui.control.RadioButton
        UploadADSBEventPanel           matlab.ui.container.Panel
        LoadADSBEventDataifAvailableLabel  matlab.ui.control.Label
        UploadMatFileButton            matlab.ui.control.Button
        CreateNewEventPanel            matlab.ui.container.Panel
        USGSEarthExplorerAPIAccountInformationPanel  matlab.ui.container.Panel
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        CreateNewEventButton           matlab.ui.control.Button
        ADSBDataSelectPanel            matlab.ui.container.Panel
        SelectFileContainingADSBDataLabel  matlab.ui.control.Label
        UploadFileButton               matlab.ui.control.Button
        TimeFramePanel                 matlab.ui.container.Panel
        EndInterference                matlab.ui.control.DatePicker
        EndDateofInterferenceUTCDatePickerLabel  matlab.ui.control.Label
        StartInterference              matlab.ui.control.DatePicker
        StartDateofInterferenceUTCDatePickerLabel  matlab.ui.control.Label
        AirportInformationPanel        matlab.ui.container.Panel
        NearestAirportName             matlab.ui.control.EditField
        NearestAirportNameEditFieldLabel  matlab.ui.control.Label
        Alt                            matlab.ui.control.NumericEditField
        LatitudeLabel_3                matlab.ui.control.Label
        Lon                            matlab.ui.control.NumericEditField
        LatitudeLabel_2                matlab.ui.control.Label
        Lat                            matlab.ui.control.NumericEditField
        LatitudeLabel                  matlab.ui.control.Label
        AirportICAO                    matlab.ui.control.EditField
        AirportICAOCodeEditFieldLabel  matlab.ui.control.Label
        AirportLocationENULabel        matlab.ui.control.Label
    end

    properties (Access = public)
        current_dir=cd;
        eventdata=struct([]);
        adsbdata=[];
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadMatFileButton
        function UploadMatFileButtonPushed(app, event)
            [file_mat,path_mat]=uigetfile('Select Interference Data Parameter Information','MultiSelect','off');
            app.eventdata=load(strcat(path_mat,file_mat));
        end

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)
            [file_csv,path_csv] = uigetfile(strcat(app.current_dir,'\*.csv'),...
                'MultiSelect','on');
            %Determine if there is one of multiple files
            if numel(string(file_csv))==1
                app.adsbdata=readtable([path_csv,file_csv],'Format','auto');
            else
                data_hour=cell(numel(file_csv),1);
                for i=1:numel(file_csv)
                    data_hour{i}=readtable([path_csv,file_csv{i}],'Format','auto');
                end
                app.adsbdata=vertcat(data_hour{:});
            end
        end

        % Button pushed function: CreateNewEventButton
        function CreateNewEventButtonPushed(app, event)
            %Populate Struct
            data=FormStruct(app);
            %Save Event (.mat file)     
            format = 'yyyy-mm-dd';
            startdate=min(data.adsbdata.time);
            enddate=max(data.adsbdata.time);
            starthour=hms(startdate);
            endhour=hms(enddate);
            filename=strcat(app.AirportICAO.Value,'-',datestr(startdate,format),...
                'H',string(starthour),'-',datestr(enddate,format),'H',...
                string(endhour),'.mat');
            [file,path] = uiputfile(filename);
            fullfilename=strcat(path,file);
            save(fullfilename,'-struct','data')
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            %Check if what type of Plot Method User selected
            %plot data
            if app.ViewDataButton.Value==1     
                data=app.eventdata.adsbdata;
                UIFigureCloseRequest(app,event)
                ADSB_Main_Plot(data)
            else %plot localization method
                idltype=app.SelectInterferenceMethod.Value;
                if idltype=="SVM"
                    %run SVM on Part 121/135 aircraft only
                    origin=cell2mat(struct2cell(app.eventdata.Airport_Info.Location));
                    icao121_135=[app.eventdata.Part121.icao;app.eventdata.Part135.icao];
                    SVM_Fit(app.eventdata.adsbdata,app.eventdata.Z,...
                        app.eventdata.RZ,icao121_135,origin);
                elseif idltype=="ConeCVX" || idltype=="ConeNonCVX"
                    ConeFit(app.eventdata.adsbdata,idltype)
                else
                    AvgCent(app.eventdata.adsbdata)
                end
                %close window
                UIFigureCloseRequest(app,event)

            end
        end

        % Selection changed function: PlotChoice
        function PlotChoiceSelectionChanged(app, event)
            selectedButton = app.PlotChoice.SelectedObject;
            if selectedButton.Text=="View Data"
                app.SelectInterferenceMethod.Enable="off";
            else
                app.SelectInterferenceMethod.Enable="on";
            end
        end

        % Callback function: CloseWindowButton, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CreateNewEventPanel = uipanel(app.UIFigure);
            app.CreateNewEventPanel.Title = 'Create New Event';
            app.AirportInformationPanel = uipanel(app.CreateNewEventPanel);
            app.AirportInformationPanel.Title = 'Airport Information';
            app.AirportLocationENULabel = uilabel(app.AirportInformationPanel);
            app.AirportLocationENULabel.Text = 'Airport Location (ENU)';
            app.AirportICAOCodeEditFieldLabel = uilabel(app.AirportInformationPanel);
            app.AirportICAOCodeEditFieldLabel.Text = 'Airport ICAO Code';
            app.AirportICAO = uieditfield(app.AirportInformationPanel, 'text');
            app.LatitudeLabel = uilabel(app.AirportInformationPanel);
            app.LatitudeLabel.Text = 'Latitude';
            app.Lat = uieditfield(app.AirportInformationPanel, 'numeric');
            app.LatitudeLabel_2 = uilabel(app.AirportInformationPanel);
            app.LatitudeLabel_2.Text = 'Longitude';
            app.Lon = uieditfield(app.AirportInformationPanel, 'numeric');
            app.LatitudeLabel_3 = uilabel(app.AirportInformationPanel);
            app.LatitudeLabel_3.Text = 'Altitude [m]';
            app.Alt = uieditfield(app.AirportInformationPanel, 'numeric');
            app.NearestAirportNameEditFieldLabel = uilabel(app.AirportInformationPanel);
            app.NearestAirportNameEditFieldLabel.Text = 'Nearest Airport Name';
            app.NearestAirportName = uieditfield(app.AirportInformationPanel, 'text');
            app.TimeFramePanel = uipanel(app.CreateNewEventPanel);
            app.TimeFramePanel.Title = 'TimeFrame';
            app.StartDateofInterferenceUTCDatePickerLabel = uilabel(app.TimeFramePanel);
            app.StartDateofInterferenceUTCDatePickerLabel.Text = 'Start Date of Interference (UTC)';
            app.StartInterference = uidatepicker(app.TimeFramePanel);
            app.EndDateofInterferenceUTCDatePickerLabel = uilabel(app.TimeFramePanel);
            app.EndDateofInterferenceUTCDatePickerLabel.Text = 'End Date of Interference (UTC)';
            app.EndInterference = uidatepicker(app.TimeFramePanel);
            app.ADSBDataSelectPanel = uipanel(app.CreateNewEventPanel);
            app.ADSBDataSelectPanel.Title = 'ADS-B Data Select';
            app.UploadFileButton = uibutton(app.ADSBDataSelectPanel, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.SelectFileContainingADSBDataLabel = uilabel(app.ADSBDataSelectPanel);
            app.SelectFileContainingADSBDataLabel.Text = 'Select File Containing ADS-B Data';
            app.CreateNewEventButton = uibutton(app.CreateNewEventPanel, 'push');
            app.CreateNewEventButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewEventButtonPushed, true);
            app.CreateNewEventButton.Text = 'Create New Event';
            app.USGSEarthExplorerAPIAccountInformationPanel = uipanel(app.CreateNewEventPanel);
            app.USGSEarthExplorerAPIAccountInformationPanel.Title = 'USGS Earth Explorer API Account Information';
            app.UsernameEditFieldLabel = uilabel(app.USGSEarthExplorerAPIAccountInformationPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.USGSEarthExplorerAPIAccountInformationPanel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.USGSEarthExplorerAPIAccountInformationPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.USGSEarthExplorerAPIAccountInformationPanel, 'text');
            app.UploadADSBEventPanel = uipanel(app.UIFigure);
            app.UploadADSBEventPanel.Title = 'Upload ADS-B Event';
            app.UploadMatFileButton = uibutton(app.UploadADSBEventPanel, 'push');
            app.UploadMatFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMatFileButtonPushed, true);
            app.UploadMatFileButton.Text = 'Upload Mat File';
            app.LoadADSBEventDataifAvailableLabel = uilabel(app.UploadADSBEventPanel);
            app.LoadADSBEventDataifAvailableLabel.Text = 'Load ADS-B Event Data if Available:';
            app.OutputDataPanel = uipanel(app.UIFigure);
            app.OutputDataPanel.Title = 'Output Data';
            app.PlotChoice = uibuttongroup(app.OutputDataPanel);
            app.PlotChoice.SelectionChangedFcn = createCallbackFcn(app, @PlotChoiceSelectionChanged, true);
            app.PlotChoice.Title = 'View Data, or Plot Localization Method';
            app.ViewDataButton = uiradiobutton(app.PlotChoice);
            app.ViewDataButton.Text = 'View Data';
            app.ViewDataButton.Value = true;
            app.PlotLocalizationMethodButton = uiradiobutton(app.PlotChoice);
            app.PlotLocalizationMethodButton.Text = 'Plot Localization Method';
            app.SelectInterferenceLocalizationMethodListBoxLabel = uilabel(app.OutputDataPanel);
            app.SelectInterferenceLocalizationMethodListBoxLabel.Enable = 'off';
            app.SelectInterferenceLocalizationMethodListBoxLabel.Text = 'Select Interference Localization Method:';
            app.SelectInterferenceMethod = uilistbox(app.OutputDataPanel);
            app.SelectInterferenceMethod.Items = {'AvgCent', 'ConeNonCVX', 'ConeCVX', 'SVM'};
            app.SelectInterferenceMethod.Enable = 'off';
            app.SelectInterferenceMethod.Value = 'ConeCVX';
            app.PlotDataButton = uibutton(app.OutputDataPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.CloseWindowButton = uibutton(app.UIFigure, 'push');
            app.CloseWindowButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseWindowButton.Text = 'Close Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN_EventUpload

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end